classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MainAxis             matlab.ui.control.UIAxes
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        DropButton           matlab.ui.control.Button
        mEditFieldLabel      matlab.ui.control.Label
        mcell                matlab.ui.control.NumericEditField
        GraphAxis            matlab.ui.control.UIAxes
        kEditFieldLabel      matlab.ui.control.Label
        kcell                matlab.ui.control.NumericEditField
        bettaEditFieldLabel  matlab.ui.control.Label
        bettacell            matlab.ui.control.NumericEditField
        gammaEditFieldLabel  matlab.ui.control.Label
        gammacell            matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        Ncell                matlab.ui.control.NumericEditField
        dtLabel              matlab.ui.control.Label
        dtcell               matlab.ui.control.NumericEditField
        gLabel               matlab.ui.control.Label
        gcell                matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        g
        m
        k
        gamma
        betta
        N
        t_max = 1000
        a = -1
        b = 1
        y
        u


    end


    methods (Access = private)

        % Callback function
        function UITableCellSelection(app, event)

        end

        % Callback function
        function StartButtonPushed(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            app1.g = app.gcell.Value;
            app1.m = app.mcell.Value;
            app1.k = app.kcell.Value;
            app1.gamma = app.gammacell.Value;
            app1.betta = app.bettacell.Value;
            app1.N = app.Ncell.Value;
            app1.y = @(x) 10*x^2 - 10;
            app1.X = linspace(app1.a,app1.b,app1.N);
            app1.u.x = []
            app1.u.y = []
            
            for ind = 1:app1.N
                app1.u.x = [app1.u.x; app1.X(ind)];
                app1.u.y = [app1.u.y; app1.y(X(ind))];
            end
            plot("MainAxis", u.x, u.y, "r")
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainAxis = uiaxes(app.UIFigure);
            title(app.MainAxis, 'Title')
            xlabel(app.MainAxis, 'X')
            ylabel(app.MainAxis, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.DropButton = uibutton(app.UIFigure, 'push');
            app.DropButton.Text = 'Drop';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mcell = uieditfield(app.UIFigure, 'numeric');
            app.mcell.Value = 1;
            app.GraphAxis = uiaxes(app.UIFigure);
            title(app.GraphAxis, 'Title')
            xlabel(app.GraphAxis, 'X')
            ylabel(app.GraphAxis, 'Y')
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kcell = uieditfield(app.UIFigure, 'numeric');
            app.kcell.Value = 1;
            app.bettaEditFieldLabel = uilabel(app.UIFigure);
            app.bettaEditFieldLabel.Text = 'betta';
            app.bettacell = uieditfield(app.UIFigure, 'numeric');
            app.bettacell.Value = 0.5;
            app.gammaEditFieldLabel = uilabel(app.UIFigure);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammacell = uieditfield(app.UIFigure, 'numeric');
            app.gammacell.Value = 0.1;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.Ncell = uieditfield(app.UIFigure, 'numeric');
            app.Ncell.Value = 21;
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt';
            app.dtcell = uieditfield(app.UIFigure, 'numeric');
            app.dtcell.Value = 0.1;
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            app.gcell = uieditfield(app.UIFigure, 'numeric');
            app.gcell.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end