classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        gcell                matlab.ui.control.NumericEditField
        gLabel               matlab.ui.control.Label
        dtcell               matlab.ui.control.NumericEditField
        dtLabel              matlab.ui.control.Label
        Ncell                matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        gammacell            matlab.ui.control.NumericEditField
        gammaEditFieldLabel  matlab.ui.control.Label
        bettacell            matlab.ui.control.NumericEditField
        bettaEditFieldLabel  matlab.ui.control.Label
        kcell                matlab.ui.control.NumericEditField
        kEditFieldLabel      matlab.ui.control.Label
        mcell                matlab.ui.control.NumericEditField
        mEditFieldLabel      matlab.ui.control.Label
        DropButton           matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        GraphAxis            matlab.ui.control.UIAxes
        MainAxis             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        g
        m
        k
        gamma
        betta
        N
        t_max = 1000
        a = -1
        b = 1
        cur_step = 1
        y
        u
        X
        l0
        dt
        num_steps
        flag
        V
        drop = false
        ball_v
        Acc_right
        V_right
        ball
        started
        y_ball
        h
        changing_rate = 1;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, das)
            
        end

        % Callback function
        function UITableCellSelection(app, event)
            
        end

        % Callback function
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            app.PrepareToStart();
            
            app.draw();
        end
    
        function PrepareToStart(app)
            app.g = app.gcell.Value;
            app.m = app.mcell.Value;
            app.k = app.kcell.Value;
            app.gamma = app.gammacell.Value;
            app.betta = app.bettacell.Value;
            app.N = app.Ncell.Value;
            app.dt = app.dtcell.Value;
            app.y = @(x) 10*x^2 - 10;
            app.X = linspace(app.a,app.b,app.N);
            app.u.x = [];
            app.u.y = [];
            app.V.x = zeros(2,app.N);
            app.V.y = zeros(2,app.N);
            app.ball_v = 0;
            app.Acc_right = [];
            app.V_right = [];
            app.ball.time = [];
            app.y_ball = 0;
            app.cur_step = 1;
            app.flag = true;
            app.StopButton.Text = "Stop"
            
            app.num_steps = app.t_max/app.dt;
            app.changing_rate = app.Slider.Value;
            cla(app.MainAxis)
            cla(app.GraphAxis)
           
            
            for ind = 1:app.N
                app.u.x = [app.u.x; app.X(ind)];
                app.u.y = [app.u.y; app.y(app.X(ind))];
            end
            
            %plot(app.MainAxis, app.u.x, app.u.y, "r")
            
            app.l0 = sqrt((app.u.y(2) - app.u.y(1))^2 + (app.u.x(2)-app.u.x(1))^2)/2;
            
            plot(app.MainAxis, app.u.x, app.u.y, 'rd--')

        end


        function draw(app)
            app.h = animatedline(app.MainAxis, app.u.x, app.u.y, "Color", "red", "Marker", "o");
            
            for i = app.cur_step:app.num_steps
                if app.flag
                    for ind = 1:app.N
                        if ind == 1
                            app.V.x(i+1,ind) = 0;
                            app.V.y(i+1,ind) = 0;
                            
                        elseif ind == app.N
                            
                            if app.drop == false
                                app.V.x(i+1,ind) = 0;
                                app.V.y(i+1,ind) = 0;
                            else
                                lL = sqrt((app.u.x(ind) - app.u.x(ind-1))^2 + (app.u.y(ind)-app.u.y(ind-1))^2);
                                FL = -(lL - app.l0) * app.k;
                                F.x = (app.u.x(ind) - app.u.x(ind-1))*FL/lL;
                                F.y = (app.u.y(ind) - app.u.y(ind-1))*FL/lL - app.m*app.g;
                                
                                app.V.x(i+1,ind) = app.V.x(i,ind) + F.x/app.m*app.dt;
                                app.V.y(i+1,ind) = app.V.y(i,ind) + F.y/app.m*app.dt;
                                
                                app.ball_v = app.ball_v - app.g*app.dt;
                                
                                app.ball.time = [app.ball.time, i*app.dt];
                                
                                a_right = sqrt((F.x/app.m)^2 + (F.y/app.m)^2);
                                v_right = sqrt((app.V.x(i,ind))^2 + (app.V.y(i,ind))^2);
                                
                                app.Acc_right = [app.Acc_right, a_right];
                                app.V_right = [app.V_right, v_right];
                                
                            end
                            
                        else
                            lR = sqrt((app.u.x(ind) - app.u.x(ind+1))^2 + (app.u.y(ind)-app.u.y(ind+1))^2);
                            lL = sqrt((app.u.x(ind) - app.u.x(ind-1))^2 + (app.u.y(ind)-app.u.y(ind-1))^2);
                            FR = -(lR - app.l0) * app.k;
                            FL = -(lL - app.l0) * app.k;
                            
                            
                            frxR = ((app.V.x(i, ind+1)-app.V.x(i, ind))*(app.u.x(ind+1)-app.u.x(ind))+(app.V.y(i, ind+1)-app.V.y(i, ind))*(app.u.y(ind+1)-app.u.y(ind)))/lR * (app.u.x(ind+1) - app.u.x(ind));
                            fryR = ((app.V.x(i, ind+1)-app.V.x(i, ind))*(app.u.x(ind+1)-app.u.x(ind))+(app.V.y(i, ind+1)-app.V.y(i, ind))*(app.u.y(ind+1)-app.u.y(ind)))/lR * (app.u.y(ind+1) - app.u.y(ind));
                            
                            frxL = ((app.V.x(i, ind-1)-app.V.x(i, ind))*(app.u.x(ind-1)-app.u.x(ind))+(app.V.y(i, ind-1)-app.V.y(i, ind))*(app.u.y(ind-1)-app.u.y(ind)))/lL * (app.u.x(ind-1) - app.u.x(ind));
                            fryL = ((app.V.x(i, ind-1)-app.V.x(i, ind))*(app.u.x(ind-1)-app.u.x(ind))+(app.V.y(i, ind-1)-app.V.y(i, ind))*(app.u.y(ind-1)-app.u.y(ind)))/lL * (app.u.y(ind-1) - app.u.y(ind));
                            
                            stx = -(frxR + frxL);
                            
                            sty = -(fryR + fryL);
                            
                            F.x = (app.u.x(ind) - app.u.x(ind+1))/lR*FR + (app.u.x(ind) - app.u.x(ind-1))*FL/lL - app.betta * (stx) - app.gamma*app.V.x(i, ind);
                            F.y = (app.u.y(ind) - app.u.y(ind+1))/lR*FR + (app.u.y(ind) - app.u.y(ind-1))*FL/lL - app.m*app.g - app.betta * (sty) - app.gamma*app.V.y(i,ind);
                            
                            app.V.x(i+1,ind) = app.V.x(i,ind) + F.x/app.m*app.dt;
                            app.V.y(i+1,ind) = app.V.y(i,ind) + F.y/app.m*app.dt;
                            
                        end
                        
                        app.u.x(ind) = app.u.x(ind) + app.V.x(i+1, ind)*app.dt;
                        app.u.y(ind) = app.u.y(ind) + app.V.y(i+1, ind)*app.dt;
                        
                        %absV = sum((app.V.x(i+1, :).*(app.V.y(i+1, :))).^2);
                        %app.EditField.Value = absV;
                        
                    end
                    
                    hold(app.MainAxis, "off");
                    
                    if app.drop == true

                        app.y_ball = app.y_ball + app.ball_v*app.dt;
                        ball_x = app.b + 0.1;
                        
                        plot(app.MainAxis, ball_x, app.y_ball, 'gd');
                        
                        hold(app.MainAxis, "on");
    
                        plot(app.GraphAxis, app.ball.time, app.Acc_right, "r", app.ball.time,app.V_right, "b");
                        
                        axis(app.GraphAxis, [min(app.ball.time), min(app.ball.time)+10, 0, max(app.Acc_right(1))+5]);
                        legend(app.GraphAxis, "a", "v");
                    end
                            
                    %app.h.addpoints(app.u.x, app.u.y)
                    
                    %grid(app.MainAxis, "on");
                    %title(app.MainAxis, ["t = ", num2str(i*app.dt)]);
                    %axis([X(1),X(length(X)),-50,0]);
                    %xlabel(app.MainAxis, 'x');
                    %ylabel(app.MainAxis, 'y');
                    app.cur_step = app.cur_step + 1;
                    
                    if mod(app.cur_step, app.changing_rate) == 0
                        plot(app.MainAxis, app.u.x, app.u.y, 'ro-');
                        drawnow;
                        %clearpoints(app.h)
                    end
                    
                    
                else
                    break
                    
                    
                end


            end


        end

        % Value changed function: mcell
        function mcellValueChanged(app, event)
            value = app.mcell.Value;
            app.m = value;
        end

        % Value changed function: Ncell
        function NcellValueChanged(app, event)
            value = app.Ncell.Value;
            app.N = value;
        end

        % Value changed function: dtcell
        function dtcellValueChanged(app, event)
            value = app.dtcell.Value;
            app.dt = value;
    
        end

        % Value changed function: gcell
        function gcellValueChanged(app, event)
            value = app.gcell.Value;
            app.g = value;
            
        end

        % Value changed function: gammacell
        function gammacellValueChanged(app, event)
            value = app.gammacell.Value;
            app.gamma = value;
        end

        % Value changed function: kcell
        function kcellValueChanged(app, event)
            value = app.kcell.Value;
            app.k = value;
        end

        % Value changed function: bettacell
        function bettacellValueChanged(app, event)
            value = app.bettacell.Value;
            app.betta = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.StopButton.Text == "Continue"
                app.StopButton.Text = "Stop";
                app.flag = true;
                app.draw();
            else
                app.StopButton.Text = "Continue";
                app.flag = false;


            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.g = app.gcell.Value;
            app.m = app.mcell.Value;
            app.k = app.kcell.Value;
            app.gamma = app.gammacell.Value;
            app.betta = app.bettacell.Value;
            app.N = app.Ncell.Value;
            app.dt = app.dtcell.Value;
            app.y = @(x) 10*x^2 - 10;
            app.X = linspace(app.a,app.b,app.N);
            app.u.x = [];
            app.u.y = [];
            app.V.x = zeros(2,app.N);
            app.V.y = zeros(2,app.N);
            app.ball_v = 0;
            app.Acc_right = [];
            app.V_right = [];
            app.ball.time = [];
            app.y_ball = 0;
            app.cur_step = 1;
            app.flag = true;
            app.drop = false;
            app.num_steps = app.t_max/app.dt;
            app.changing_rate = 1;
            app.Slider.Value = 1;
            cla(app.MainAxis)
            cla(app.GraphAxis)
           
            
            for ind = 1:app.N
                app.u.x = [app.u.x; app.X(ind)];
                app.u.y = [app.u.y; app.y(app.X(ind))];
            end
            
            %plot(app.MainAxis, app.u.x, app.u.y, "r")
            
            app.l0 = sqrt((app.u.y(2) - app.u.y(1))^2 + (app.u.x(2)-app.u.x(1))^2)/2;
            
            plot(app.MainAxis, app.u.x, app.u.y, 'rd--')

        end

        % Button pushed function: DropButton
        function DropButtonPushed(app, event)
            app.drop = true;
            app.gammacell.Value = 0;
            app.bettacell.Value = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            
            app.changing_rate = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainAxis = uiaxes(app.UIFigure);
            title(app.MainAxis, 'Title')
            xlabel(app.MainAxis, 'X')
            ylabel(app.MainAxis, 'Y')
            app.GraphAxis = uiaxes(app.UIFigure);
            title(app.GraphAxis, 'Title')
            xlabel(app.GraphAxis, 'X')
            ylabel(app.GraphAxis, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DropButton = uibutton(app.UIFigure, 'push');
            app.DropButton.ButtonPushedFcn = createCallbackFcn(app, @DropButtonPushed, true);
            app.DropButton.Text = 'Drop';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mcell = uieditfield(app.UIFigure, 'numeric');
            app.mcell.ValueChangedFcn = createCallbackFcn(app, @mcellValueChanged, true);
            app.mcell.Value = 1;
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kcell = uieditfield(app.UIFigure, 'numeric');
            app.kcell.ValueChangedFcn = createCallbackFcn(app, @kcellValueChanged, true);
            app.kcell.Value = 2;
            app.bettaEditFieldLabel = uilabel(app.UIFigure);
            app.bettaEditFieldLabel.Text = 'betta';
            app.bettacell = uieditfield(app.UIFigure, 'numeric');
            app.bettacell.ValueChangedFcn = createCallbackFcn(app, @bettacellValueChanged, true);
            app.bettacell.Value = 0.1;
            app.gammaEditFieldLabel = uilabel(app.UIFigure);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammacell = uieditfield(app.UIFigure, 'numeric');
            app.gammacell.ValueChangedFcn = createCallbackFcn(app, @gammacellValueChanged, true);
            app.gammacell.Value = 0.5;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.Ncell = uieditfield(app.UIFigure, 'numeric');
            app.Ncell.ValueChangedFcn = createCallbackFcn(app, @NcellValueChanged, true);
            app.Ncell.Value = 21;
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt';
            app.dtcell = uieditfield(app.UIFigure, 'numeric');
            app.dtcell.ValueChangedFcn = createCallbackFcn(app, @dtcellValueChanged, true);
            app.dtcell.Value = 0.1;
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            app.gcell = uieditfield(app.UIFigure, 'numeric');
            app.gcell.ValueChangedFcn = createCallbackFcn(app, @gcellValueChanged, true);
            app.gcell.Value = 4;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end